classdef Plot_Frequency_Response < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AddPoleorZeroPanel             matlab.ui.container.Panel
        RealEditFieldLabel             matlab.ui.control.Label
        RealEditField                  matlab.ui.control.NumericEditField
        ImaginaryEditFieldLabel        matlab.ui.control.Label
        ImaginaryEditField             matlab.ui.control.NumericEditField
        AddButton                      matlab.ui.control.Button
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        PlotSettingButtonGroup         matlab.ui.container.ButtonGroup
        MagnitudeLinearButton          matlab.ui.control.RadioButton
        MagnitudedbButton              matlab.ui.control.RadioButton
        PhaseargButton                 matlab.ui.control.RadioButton
        PhaseARGButton                 matlab.ui.control.RadioButton
        InformationPanel               matlab.ui.container.Panel
        NumberofPolesEditFieldLabel    matlab.ui.control.Label
        NumberofPolesEditField         matlab.ui.control.NumericEditField
        NumberofZerosEditField_2Label  matlab.ui.control.Label
        NumberofZerosEditField_2       matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ButtonValue;
        b = [1]; % Zeros (Numinator   Coefficients)
        a = [1]; % Poles (Denuminator Coefficients)
    end
    
    methods (Access = private)
        
        function DSP0(app) % Define Main Function
            
            
            %%%%%%%%%%
            
            if app.ButtonValue == 1 
               Pole_or_Zero = app.TypeDropDown.Value ; % Determine Pole/Zero
               Real = app.RealEditField.Value; % Get the real part
               Imaginary = app.ImaginaryEditField.Value; % Get the imaginary part
               x = [1 -(Real+1j*Imaginary)]; % Set the coefficients
               switch Pole_or_Zero
                   case "Pole"
                       app.a = conv(app.a , x); %convolve with denuminator
                       app.NumberofPolesEditField.Value = app.NumberofPolesEditField.Value + 1 ; %add one to poles number
                   case "Zero"
                       app.b = conv(app.b , x); %convolve with numinator
                       app.NumberofZerosEditField_2.Value = app.NumberofZerosEditField_2.Value + 1 ; %add one to zeros number
               end
               app.ButtonValue = 0 ;
            end
            
            %%%%%%%%%%
            
            selectedButton = app.PlotSettingButtonGroup.SelectedObject.Text; %Find The Choosen Option
            [h,w] = freqz(app.b,app.a); % frequency Response Information
            switch selectedButton % Plot the Frequency Responses with Respect to Choosen Option
                case "Magnitude (Linear)"
                  app.UIAxes.Title.String  = "Frequency Response (Magnitude (Linear) )"; %Title
                  app.UIAxes.YLabel.String = "Magnitude (Linear)"; %Y axes Name
                  plot(app.UIAxes,w/pi,abs(h));
                case "Magnitude (db)"
                  app.UIAxes.Title.String  = "Frequency Response (Magnitude (db) )";
                  app.UIAxes.YLabel.String = "Magnitude (db)";
                  plot(app.UIAxes,w/pi,20*log10(abs(h))); 
                case "Phase (arg)"
                  app.UIAxes.Title.String  = "Frequency Response (Phase (UnWrapped) )";
                  app.UIAxes.YLabel.String = "Phase (arg)";
                  plot(app.UIAxes,w/pi,phase(h)*180/pi); 
                case "Phase (ARG)" 
                  app.UIAxes.Title.String  = "Frequency Response (Phase (Wrapped) )";
                  app.UIAxes.YLabel.String = "Phase (ARG)";
                  plot(app.UIAxes,w/pi,angle(h)*180/pi); 
            end
            
            %%%%%%%%%%
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: PlotSettingButtonGroup
        function PlotSettingButtonGroupSelectionChanged(app, event)
            DSP0(app); % Reference to Main Function
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
           app.ButtonValue = 1 ; % Set a Value for Runing this Function in Main Function
           DSP0(app); % Reference to Main Function
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddPoleorZeroPanel = uipanel(app.UIFigure);
            app.AddPoleorZeroPanel.Title = 'Add Pole or Zero';
            app.RealEditFieldLabel = uilabel(app.AddPoleorZeroPanel);
            app.RealEditFieldLabel.Text = 'Real';
            app.RealEditField = uieditfield(app.AddPoleorZeroPanel, 'numeric');
            app.ImaginaryEditFieldLabel = uilabel(app.AddPoleorZeroPanel);
            app.ImaginaryEditFieldLabel.Text = 'Imaginary';
            app.ImaginaryEditField = uieditfield(app.AddPoleorZeroPanel, 'numeric');
            app.AddButton = uibutton(app.AddPoleorZeroPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.TypeDropDownLabel = uilabel(app.AddPoleorZeroPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.AddPoleorZeroPanel);
            app.TypeDropDown.Items = {'Pole', 'Zero'};
            app.TypeDropDown.Value = 'Pole';
            app.PlotSettingButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotSettingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotSettingButtonGroupSelectionChanged, true);
            app.PlotSettingButtonGroup.Title = 'Plot Setting';
            app.MagnitudeLinearButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.MagnitudeLinearButton.Text = 'Magnitude (Linear)';
            app.MagnitudeLinearButton.Value = true;
            app.MagnitudedbButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.MagnitudedbButton.Text = 'Magnitude (db)';
            app.PhaseargButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.PhaseargButton.Text = 'Phase (arg)';
            app.PhaseARGButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.PhaseARGButton.Text = 'Phase (ARG)';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.NumberofPolesEditFieldLabel = uilabel(app.InformationPanel);
            app.NumberofPolesEditFieldLabel.Text = 'Number of Poles :';
            app.NumberofPolesEditField = uieditfield(app.InformationPanel, 'numeric');
            app.NumberofPolesEditField.Editable = 'off';
            app.NumberofZerosEditField_2Label = uilabel(app.InformationPanel);
            app.NumberofZerosEditField_2Label.Text = 'Number of Zeros :';
            app.NumberofZerosEditField_2 = uieditfield(app.InformationPanel, 'numeric');
            app.NumberofZerosEditField_2.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frequency Response')
            xlabel(app.UIAxes, 'Normalized Frequency (*pi rad/sample)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_Frequency_Response

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end